classdef building_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        JointAnglesPanel         matlab.ui.container.Panel
        Label                    matlab.ui.control.Label
        Slider_joint1            matlab.ui.control.Slider
        Slider_3Label            matlab.ui.control.Label
        Slider_joint2            matlab.ui.control.Slider
        Slider_6Label            matlab.ui.control.Label
        Slider_joint3            matlab.ui.control.Slider
        Slider_5Label            matlab.ui.control.Label
        Slider_joint4            matlab.ui.control.Slider
        Slider_4Label            matlab.ui.control.Label
        Slider_joint5            matlab.ui.control.Slider
        current_joint_angles     matlab.ui.control.Label
        Slider_5Label_3          matlab.ui.control.Label
        Slider_joint6            matlab.ui.control.Slider
        Label_2                  matlab.ui.control.Label
        Slider_joint7            matlab.ui.control.Slider
        Label_3                  matlab.ui.control.Label
        Slider_joint8            matlab.ui.control.Slider
        HomeButton               matlab.ui.control.Button
        ZeroButton               matlab.ui.control.Button
        SetPosition1Button       matlab.ui.control.Button
        SetPostion2Button        matlab.ui.control.Button
        SetPosition3Button       matlab.ui.control.Button
        GoToPosition1Button      matlab.ui.control.Button
        GoToPosition2Button      matlab.ui.control.Button
        GoToPosition3Button      matlab.ui.control.Button
        Set_joint_angles1_label  matlab.ui.control.Label
        Set_joint_angles2_label  matlab.ui.control.Label
        Set_joint_angles3_label  matlab.ui.control.Label
        axes_arm                 matlab.ui.control.UIAxes
        x_ValueLabel             matlab.ui.control.Label
        y_ValueLabel             matlab.ui.control.Label
        z_ValueLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        jointAngles = zeros(1,8); % Stores the joint angles for each arm according to the DH parameters
        position1Angles = [-33.8 0 0 0 0];  %stores the first saved position
        position2Angles = [0 0 0 0 0];  %stores the second saved position
        position3Angles = [0 90 0 -90 90];  %stores the third saved position
        link1Patch;
        link1Vertices;
        link2Patch;
        link2Vertices;
        link3Patch;
        link3Vertices;
        link4Patch;
        link4Vertices;
        link5Patch;
        link5Vertices;
        link6Patch;
        link6Vertices;
        link7Patch;
        link7Vertices;
        link8Patch;
        link8Vertices;


    end
    
    methods (Access = private)
        
        function jointSliderChange(app)
            app.jointAngles(1) = round (app.Slider_joint1.Value);
            app.jointAngles(2) = round (app.Slider_joint2.Value);
            app.jointAngles(3) = round (app.Slider_joint3.Value);
            app.jointAngles(4) = round (app.Slider_joint4.Value);
            app.jointAngles(5) = round (app.Slider_joint5.Value);
            app.jointAngles(6) = round (app.Slider_joint6.Value);
            app.jointAngles(7) = round (app.Slider_joint7.Value);
            app.jointAngles(8) = round (app.Slider_joint8.Value);
            
            jointAnglesStr = sprintf("%d  %d  %d  %d  %d %d %d %d", app.jointAngles(1),app.jointAngles(2),...
                app.jointAngles(3),app.jointAngles(4),app.jointAngles(5),app.jointAngles(6),app.jointAngles(7),app.jointAngles(8)  );
            app.current_joint_angles.Text = jointAnglesStr;
            app.updateArm();
            
        end
        
        function updateArm(app);
            % Todo: develope this function in another file then bring it
            % in!
            % DONE: Use the A matricies to form the T0_n matricies.
            [A1, A2, A3, A4, A5, A6, A7, A8, A9, A10, A11, A12] = ...
                makeHomogeneousTransformations(app.jointAngles(1), app.jointAngles(2),app.jointAngles(3),app.jointAngles(4),app.jointAngles(5),app.jointAngles(6),app.jointAngles(7),app.jointAngles(8));
            
            
            T0_1 = A1*A2;
            T0_2 = A1*A3;
            T0_3 = A4*A5;
            T0_4 = A4*A6;
            
            T0_5 = A7*A8;
            T0_6 = A7*A9;
            T0_7 = A10*A11;
            T0_8 = A10*A12;
            
            % DONE: Use the T matricies to transform the patch vertices
            link1verticesWRTground = T0_1 * app.link1Vertices;
            link2verticesWRTground = T0_2 * app.link2Vertices;
            link3verticesWRTground = T0_3 * app.link3Vertices;
            link4verticesWRTground = T0_4 * app.link4Vertices;
            
            link5verticesWRTground = T0_5 * app.link5Vertices;
            link6verticesWRTground = T0_6 * app.link6Vertices;
            link7verticesWRTground = T0_7 * app.link7Vertices;
            link8verticesWRTground = T0_8 * app.link8Vertices;


            % DONE: Update the patches with the new vertices
            set(app.link1Patch,'Vertices', link1verticesWRTground(1:3,:)');  %trims the vector of ones off that were needed for the transformation
            set(app.link2Patch,'Vertices', link2verticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3verticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4verticesWRTground(1:3,:)');
            
            set(app.link5Patch,'Vertices', link5verticesWRTground(1:3,:)');  %trims the vector of ones off that were needed for the transformation
            set(app.link6Patch,'Vertices', link6verticesWRTground(1:3,:)');
            set(app.link7Patch,'Vertices', link7verticesWRTground(1:3,:)');
            set(app.link8Patch,'Vertices', link8verticesWRTground(1:3,:)');
            
            
            %             Display the XYZ of the gripper tip.
            % Update x, y, and z using the gripper (end effector) origin.
            dhOrigin = [0 0 0 1]';
            gripperWRTground = T0_5 * dhOrigin;
            fprintf("TODO: Put each individual value into it's own GUI label X = %.3f, Y = %.3f, Z = %.3f)\n", gripperWRTground(1), gripperWRTground(2), gripperWRTground(3));
            
            %DONE: Update the x value label with
            app.x_ValueLabel.Text = ("x: " + num2str(round(gripperWRTground(1), 1)))
            app.y_ValueLabel.Text = ("y: " +num2str(round(gripperWRTground(2), 1)))
            app.z_ValueLabel.Text = ("z: " +num2str(round(gripperWRTground(3), 1)))
            
            %check for collision
            links = [app.link1Patch, app.link2Patch, app.link3Patch, app.link4Patch...
                app.link5Patch, app.link6Patch, app.link7Patch, app.link8Patch];
            
            app.Set_joint_angles1_label.Text = "SAFE;"      %reset flag text
            
            for i = 1:7
                for j = 2:8
                    if i <j
                        detect_collision (app, links(i), links(j));
                        fprintf ("%d %d \n",i,j)
                    end
                end
            end


        end
        
        function goToPosition(app,newPosition)
            app.jointAngles = newPosition;
            jointAnglesStr = sprintf("%d  %d  %d  %d  %d  %d  %d  %d",...
                app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4),app.jointAngles(5),app.jointAngles(6),app.jointAngles(7),app.jointAngles(8) );
            app.current_joint_angles.Text = jointAnglesStr;
            app.Slider_joint1.Value = app.jointAngles(1);
            app.Slider_joint2.Value = app.jointAngles(2);
            app.Slider_joint3.Value = app.jointAngles(3);
            app.Slider_joint4.Value = app.jointAngles(4);
            app.Slider_joint5.Value = app.jointAngles(5);
            app.Slider_joint6.Value = app.jointAngles(6);
            app.Slider_joint7.Value = app.jointAngles(7);
            app.Slider_joint8.Value = app.jointAngles(8);
            app.updateArm();
            
        end


        function detect_collision(app, joint1, joint2)
            flag = GJK(joint1,joint2,50);
            if (flag == 1)
                app.Set_joint_angles1_label.Text = "COLLISION";               
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DhArm_OpeningFcn(app)
            clc
            
            %TODO: Do the setup work for the axes that will have the arm
            %done in a another file first then bring it in here
            
            % Prepare the arm axes
            view(app.axes_arm, [0 0 1]);                    %sets the view to top view
            axis(app.axes_arm, [-20000 20000 -15000 15000 -10000 10000]);
            
            %             set(app.axes_arm, 'Visible', 'off');
            
            
            % Create vertices for all the patches
            %makeLink0(app.axes_arm, [.5 .5 .5]);  % Doesn't move. No need to save.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            
            
            app.link1Patch = makeStlLink("STL/Theater_section_no_collor.STL",app.axes_arm,[1 0 0]);
            app.link1Vertices = get(app.link1Patch, 'Vertices').';
            app.link1Vertices(4,:) = ones (1,size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("STL/Bench_section_nocollor.STL",app.axes_arm,[0 0 1]);
            app.link2Vertices = get(app.link2Patch, 'Vertices').';
            app.link2Vertices(4,:) = ones (1,size(app.link2Vertices,2));
            %
            app.link3Patch = makeStlLink("STL/Bot_Theater_section_no_collor.STL",app.axes_arm,[0 1 0]);
            app.link3Vertices = get(app.link3Patch, 'Vertices').';
            app.link3Vertices(4,:) = ones (1,size(app.link3Vertices,2));
            %
            app.link4Patch = makeStlLink("STL/bot_Bench_section_nocollor.STL",app.axes_arm,[0 0 0]);
            app.link4Vertices = get(app.link4Patch, 'Vertices').';
            app.link4Vertices(4,:) = ones (1,size(app.link4Vertices,2));
            
            
            %second building
            
            app.link5Patch = makeStlLink("STL/Theater_section_no_collor.STL",app.axes_arm,[1 0 0]);
            app.link5Vertices = get(app.link5Patch, 'Vertices').';
            app.link5Vertices(4,:) = ones (1,size(app.link5Vertices,2));
            
            app.link6Patch = makeStlLink("STL/Bench_section_nocollor.STL",app.axes_arm,[0 0 1]);
            app.link6Vertices = get(app.link6Patch, 'Vertices').';
            app.link6Vertices(4,:) = ones (1,size(app.link6Vertices,2));
            %
            app.link7Patch = makeStlLink("STL/Bot_Theater_section_no_collor.STL",app.axes_arm,[0 1 0]);
            app.link7Vertices = get(app.link7Patch, 'Vertices').';
            app.link7Vertices(4,:) = ones (1,size(app.link7Vertices,2));
            %
            app.link8Patch = makeStlLink("STL/bot_Bench_section_nocollor.STL",app.axes_arm,[0 0 0]);
            app.link8Vertices = get(app.link8Patch, 'Vertices').';
            app.link8Vertices(4,:) = ones (1,size(app.link8Vertices,2));
            updateArm(app);
            
            
        end

        % Value changed function: Slider_joint1
        function slider_joint1_Callback(app, event)
            app.jointSliderChange();
        end

        % Value changed function: Slider_joint2
        function slider_joint2_Callback(app, event)
            app.jointSliderChange();
        end

        % Value changed function: Slider_joint3
        function slider_joint3_Callback(app, event)
            app.jointSliderChange();
        end

        % Value changed function: Slider_joint4
        function slider_joint4_Callback(app, event)
            app.jointSliderChange();
        end

        % Value changed function: Slider_joint5
        function slider_joint5_Callback(app, event)
            app.jointSliderChange();
        end

        % Value changed function: Slider_joint6
        function slider_joint6_Callback(app, event)
            app.jointSliderChange();
            
        end

        % Value changed function: Slider_joint7
        function slider_joint7_Callback(app, event)
            app.jointSliderChange();
            
        end

        % Value changed function: Slider_joint8
        function slider_joint8_Callback(app, event)
            app.jointSliderChange();
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.goToPosition([0 0 0 0 0 0 0 0]);
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            app.goToPosition([0 0 0 0 0 0 0 0]);
        end

        % Button pushed function: GoToPosition1Button
        function GoToPosition1Pressed(app, event)
            app.goToPosition(app.position1Angles);
        end

        % Button pushed function: GoToPosition2Button
        function GoToPosition2Pressed(app, event)
            app.goToPosition(app.position2Angles);
        end

        % Button pushed function: GoToPosition3Button
        function GoToPosition3Pressed(app, event)
            app.goToPosition(app.position3Angles);
        end

        % Button pushed function: SetPosition1Button
        function SetPosition1Pressed(app, event)
            app.position1Angles = app.jointAngles;
            jointAnglesStr = sprintf("%d  %d  %d  %d  %d", app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4),app.jointAngles(5) );
            app.Set_joint_angles1_label.Text = jointAnglesStr;
        end

        % Button pushed function: SetPostion2Button
        function SetPosition2Pressed(app, event)
            app.position2Angles = app.jointAngles;
            jointAnglesStr = sprintf("%d  %d  %d  %d  %d", app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4),app.jointAngles(5) );
            app.Set_joint_angles2_label.Text = jointAnglesStr;
        end

        % Button pushed function: SetPosition3Button
        function SetPosition3Pressed(app, event)
            app.position3Angles = app.jointAngles;
            jointAnglesStr = sprintf("%d  %d  %d  %d  %d", app.jointAngles(1),app.jointAngles(2),app.jointAngles(3),app.jointAngles(4),app.jointAngles(5) );
            app.Set_joint_angles3_label.Text = jointAnglesStr;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.JointAnglesPanel = uipanel(app.UIFigure);
            app.JointAnglesPanel.Title = 'Joint Angles';
            app.Label = uilabel(app.JointAnglesPanel);
            app.Label.Text = '';
            app.Slider_joint1 = uislider(app.JointAnglesPanel);
            app.Slider_joint1.Limits = [-90 90];
            app.Slider_joint1.ValueChangedFcn = createCallbackFcn(app, @slider_joint1_Callback, true);
            app.Slider_3Label = uilabel(app.JointAnglesPanel);
            app.Slider_3Label.Text = '';
            app.Slider_joint2 = uislider(app.JointAnglesPanel);
            app.Slider_joint2.Limits = [-90 90];
            app.Slider_joint2.ValueChangedFcn = createCallbackFcn(app, @slider_joint2_Callback, true);
            app.Slider_6Label = uilabel(app.JointAnglesPanel);
            app.Slider_6Label.Text = '';
            app.Slider_joint3 = uislider(app.JointAnglesPanel);
            app.Slider_joint3.Limits = [-90 90];
            app.Slider_joint3.ValueChangedFcn = createCallbackFcn(app, @slider_joint3_Callback, true);
            app.Slider_5Label = uilabel(app.JointAnglesPanel);
            app.Slider_5Label.Text = '';
            app.Slider_joint4 = uislider(app.JointAnglesPanel);
            app.Slider_joint4.Limits = [-90 90];
            app.Slider_joint4.ValueChangedFcn = createCallbackFcn(app, @slider_joint4_Callback, true);
            app.Slider_4Label = uilabel(app.JointAnglesPanel);
            app.Slider_4Label.Text = '';
            app.Slider_joint5 = uislider(app.JointAnglesPanel);
            app.Slider_joint5.Limits = [-90 90];
            app.Slider_joint5.ValueChangedFcn = createCallbackFcn(app, @slider_joint5_Callback, true);
            app.current_joint_angles = uilabel(app.JointAnglesPanel);
            app.current_joint_angles.Text = '';
            app.Slider_5Label_3 = uilabel(app.JointAnglesPanel);
            app.Slider_5Label_3.Text = '';
            app.Slider_joint6 = uislider(app.JointAnglesPanel);
            app.Slider_joint6.Limits = [-90 90];
            app.Slider_joint6.ValueChangedFcn = createCallbackFcn(app, @slider_joint6_Callback, true);
            app.Label_2 = uilabel(app.JointAnglesPanel);
            app.Label_2.Text = '';
            app.Slider_joint7 = uislider(app.JointAnglesPanel);
            app.Slider_joint7.Limits = [-90 90];
            app.Slider_joint7.ValueChangedFcn = createCallbackFcn(app, @slider_joint7_Callback, true);
            app.Label_3 = uilabel(app.JointAnglesPanel);
            app.Label_3.Text = '';
            app.Slider_joint8 = uislider(app.JointAnglesPanel);
            app.Slider_joint8.Limits = [-90 90];
            app.Slider_joint8.ValueChangedFcn = createCallbackFcn(app, @slider_joint8_Callback, true);
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ZeroButton = uibutton(app.UIFigure, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.SetPosition1Button = uibutton(app.UIFigure, 'push');
            app.SetPosition1Button.ButtonPushedFcn = createCallbackFcn(app, @SetPosition1Pressed, true);
            app.SetPosition1Button.Text = 'Set Position 1';
            app.SetPostion2Button = uibutton(app.UIFigure, 'push');
            app.SetPostion2Button.ButtonPushedFcn = createCallbackFcn(app, @SetPosition2Pressed, true);
            app.SetPostion2Button.Text = 'Set Postion 2';
            app.SetPosition3Button = uibutton(app.UIFigure, 'push');
            app.SetPosition3Button.ButtonPushedFcn = createCallbackFcn(app, @SetPosition3Pressed, true);
            app.SetPosition3Button.Text = 'Set Position 3';
            app.GoToPosition1Button = uibutton(app.UIFigure, 'push');
            app.GoToPosition1Button.ButtonPushedFcn = createCallbackFcn(app, @GoToPosition1Pressed, true);
            app.GoToPosition1Button.Text = 'Go To Position 1';
            app.GoToPosition2Button = uibutton(app.UIFigure, 'push');
            app.GoToPosition2Button.ButtonPushedFcn = createCallbackFcn(app, @GoToPosition2Pressed, true);
            app.GoToPosition2Button.Text = 'Go To Position 2';
            app.GoToPosition3Button = uibutton(app.UIFigure, 'push');
            app.GoToPosition3Button.ButtonPushedFcn = createCallbackFcn(app, @GoToPosition3Pressed, true);
            app.GoToPosition3Button.Text = 'Go To Position 3';
            app.Set_joint_angles1_label = uilabel(app.UIFigure);
            app.Set_joint_angles1_label.Text = '';
            app.Set_joint_angles2_label = uilabel(app.UIFigure);
            app.Set_joint_angles2_label.Text = '';
            app.Set_joint_angles3_label = uilabel(app.UIFigure);
            app.Set_joint_angles3_label.Text = '';
            app.axes_arm = uiaxes(app.UIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            app.x_ValueLabel = uilabel(app.UIFigure);
            app.y_ValueLabel = uilabel(app.UIFigure);
            app.y_ValueLabel.Text = 'Label2';
            app.z_ValueLabel = uilabel(app.UIFigure);
            app.z_ValueLabel.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = building_simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @DhArm_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end